classdef EyeCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        saveButton                     matlab.ui.control.Button
        yoffsetEditFieldLabel          matlab.ui.control.Label
        yoffset                        matlab.ui.control.NumericEditField
        ygainEditFieldLabel            matlab.ui.control.Label
        ygain                          matlab.ui.control.NumericEditField
        xoffsetEditFieldLabel          matlab.ui.control.Label
        xoffset                        matlab.ui.control.NumericEditField
        xgain                          matlab.ui.control.NumericEditField
        xgainEditFieldLabel            matlab.ui.control.Label
        saveasButton                   matlab.ui.control.Button
        openButton                     matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        Image                          matlab.ui.control.Image
        EraseButton                    matlab.ui.control.Button
        SetButton                      matlab.ui.control.Button
        AutoNextTargetSwitch           matlab.ui.control.Switch
        AutoNextTargetSwitchLabel      matlab.ui.control.Label
        TargetManualSelectButtonGroup  matlab.ui.container.ButtonGroup
        Lamp_9                         matlab.ui.control.Lamp
        Lamp_8                         matlab.ui.control.Lamp
        Lamp_7                         matlab.ui.control.Lamp
        Lamp_6                         matlab.ui.control.Lamp
        Lamp_5                         matlab.ui.control.Lamp
        Lamp_4                         matlab.ui.control.Lamp
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        Lamp                           matlab.ui.control.Lamp
        T9Button                       matlab.ui.control.RadioButton
        T8Button                       matlab.ui.control.RadioButton
        T7Button                       matlab.ui.control.RadioButton
        T6Button                       matlab.ui.control.RadioButton
        T5Button                       matlab.ui.control.RadioButton
        T4Button                       matlab.ui.control.RadioButton
        T3Button                       matlab.ui.control.RadioButton
        T2Button                       matlab.ui.control.RadioButton
        T1Button                       matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        eye_ini = IniConfig(); % Description
        screen_ini = IniConfig();
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('/opt/Trellis/Tools/xippmex');
            % children = get(app.UIFigure,'Children');
            % set(children,'Enable','off');

            evalin('base','clear'); %clear world
            warning('off','MATLAB:structOnObject'); %remove an annoying warning

            funpath=which(mfilename);
            cd(fileparts(funpath)); %switch to the filepath of the app

            addpath(genpath(fileparts(which(mfilename))));

            addpath(genpath(fileparts(which('LiveParams')))); % add filepaths

            %% initialize filepaths for xippmex and debug mode etc
            addpath(genpath(pwd));    
            xippath=genpath(fileparts(which('xippmex')));

            %% set up daq. Modify the specifics in the setupDAQ function
            % setupDAQ(app);

            %% set up initial conditions from previous session
            isini=app.screen_ini.ReadFile('inis/ScreenParams.ini');
            last_file=app.screen_ini.GetValues('last eye calibration file','last_file');
            isini_eye=app.eye_ini.ReadFile(fullfile('inis',last_file));
            
            if ~isini && isini_eye
                errordlg('Screen Parameters ini not found. Missing or in the wrong path.')
            elseif ~isini_eye && isini
                errordlg('Eye Calibration ini not found. Missing or in the wrong path.')
            elseif ~isini_eye && ~isini
                errordlg('Eye Calibration and Screen Parameters inis not found. Missing or in the wrong path.')
            elseif isini && isini_eye
                app.EditField.Value=last_file;
                app.xoffset.Value=app.eye_ini.GetValues('eye calibration','xoffset');
                app.xgain.Value=app.eye_ini.GetValues('eye calibration','xgain');
                app.yoffset.Value=app.eye_ini.GetValues('eye calibration','yoffset');
                app.ygain.Value=app.eye_ini.GetValues('eye calibration','ygain');
            end

            %% start the mh structure
            mh=internal;
            mh.graphicsport = udpport("LocalPort",2020); %start udp port

            %% set up listener for udpport
            configureCallback(mh.graphicsport,"terminator",@checkudp);

            function checkudp(obj,~)
                com=readline(obj);
                eval(com);
            end

            %% check if graphics are already up
            isGraphicsReady=0;
            for i=1:2 % check for graphics i times in case they're unresponsive
                str=('writeline(graphicsport,''isGraphicsReady=1;'',''0.0.0.0'',2020);');
                try
                    mh.evalgraphics(str);
                    com=readline(mh.graphicsport);
                    eval(com)
                end
            end


            %% graphics go brrrrrr
            if ~isGraphicsReady
                system('gnome-terminal -- sh -c "cd ./background_functions/; taskset -c 2,3,4 matlab -noawt -r ''GraphicsHandler''"')
            end

            %% start flipping screens so we can see the eye
            mh.eye=eyeinfo;

            mh.graphicsport.UserData.sendcheck=0;
            isGraphicsReady=0;

            while isGraphicsReady==0
                pause(0.1)
                str=('writeline(graphicsport,''isGraphicsReady=1;'',''0.0.0.0'',2020);');
                try
                    mh.evalgraphics(str);
                    com=readline(mh.graphicsport);
                    eval(com)
                end
            end
            %% add parameters
            mh=makeparams(mh);
            app.insToTxtbox('ready')
            set(children,'Enable','on');

            %% show eye position
            while ~app.running %&& ~app.STOPButton.Value
                if app.RewardButton.Value
                    if ~mh.rew.rewon
                        mh.reward(mh.getint('reward_gui'));
                        app.RewardButton.Value=1;
                    else
                        app.RewardButton.Value=0;
                    end
                end

                drawnow

                mh.rewcheck(app);

                calibration_check(app,mh)
                assignin('base','mh',mh)
            end

        end

        % Button pushed function: saveasButton
        function saveasButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TargetManualSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.TargetManualSelectButtonGroup.Title = 'Target Manual Select';
            app.T1Button = uiradiobutton(app.TargetManualSelectButtonGroup);
            app.T1Button.Text = 'T1';
            app.T1Button.Value = true;
            app.T2Button = uiradiobutton(app.TargetManualSelectButtonGroup);
            app.T2Button.Text = 'T2';
            app.T3Button = uiradiobutton(app.TargetManualSelectButtonGroup);
            app.T3Button.Text = 'T3';
            app.T4Button = uiradiobutton(app.TargetManualSelectButtonGroup);
            app.T4Button.Text = 'T4';
            app.T5Button = uiradiobutton(app.TargetManualSelectButtonGroup);
            app.T5Button.Text = 'T5';
            app.T6Button = uiradiobutton(app.TargetManualSelectButtonGroup);
            app.T6Button.Text = 'T6';
            app.T7Button = uiradiobutton(app.TargetManualSelectButtonGroup);
            app.T7Button.Text = 'T7';
            app.T8Button = uiradiobutton(app.TargetManualSelectButtonGroup);
            app.T8Button.Text = 'T8';
            app.T9Button = uiradiobutton(app.TargetManualSelectButtonGroup);
            app.T9Button.Text = 'T9';
            app.Lamp = uilamp(app.TargetManualSelectButtonGroup);
            app.Lamp.Enable = 'off';
            app.Lamp_2 = uilamp(app.TargetManualSelectButtonGroup);
            app.Lamp_2.Enable = 'off';
            app.Lamp_3 = uilamp(app.TargetManualSelectButtonGroup);
            app.Lamp_3.Enable = 'off';
            app.Lamp_4 = uilamp(app.TargetManualSelectButtonGroup);
            app.Lamp_4.Enable = 'off';
            app.Lamp_5 = uilamp(app.TargetManualSelectButtonGroup);
            app.Lamp_5.Enable = 'off';
            app.Lamp_6 = uilamp(app.TargetManualSelectButtonGroup);
            app.Lamp_6.Enable = 'off';
            app.Lamp_7 = uilamp(app.TargetManualSelectButtonGroup);
            app.Lamp_7.Enable = 'off';
            app.Lamp_8 = uilamp(app.TargetManualSelectButtonGroup);
            app.Lamp_8.Enable = 'off';
            app.Lamp_9 = uilamp(app.TargetManualSelectButtonGroup);
            app.Lamp_9.Enable = 'off';
            app.AutoNextTargetSwitchLabel = uilabel(app.UIFigure);
            app.AutoNextTargetSwitchLabel.Text = 'Auto Next Target';
            app.AutoNextTargetSwitch = uiswitch(app.UIFigure, 'slider');
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.Text = 'Set';
            app.EraseButton = uibutton(app.UIFigure, 'push');
            app.EraseButton.Text = 'Erase';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'Target Positions.png');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.openButton = uibutton(app.UIFigure, 'push');
            app.openButton.Text = 'open';
            app.saveasButton = uibutton(app.UIFigure, 'push');
            app.saveasButton.ButtonPushedFcn = createCallbackFcn(app, @saveasButtonPushed, true);
            app.saveasButton.Text = 'save as';
            app.xgainEditFieldLabel = uilabel(app.UIFigure);
            app.xgainEditFieldLabel.Text = 'x gain';
            app.xgain = uieditfield(app.UIFigure, 'numeric');
            app.xoffset = uieditfield(app.UIFigure, 'numeric');
            app.xoffsetEditFieldLabel = uilabel(app.UIFigure);
            app.xoffsetEditFieldLabel.Text = 'x offset';
            app.ygain = uieditfield(app.UIFigure, 'numeric');
            app.ygainEditFieldLabel = uilabel(app.UIFigure);
            app.ygainEditFieldLabel.Text = 'y gain';
            app.yoffset = uieditfield(app.UIFigure, 'numeric');
            app.yoffsetEditFieldLabel = uilabel(app.UIFigure);
            app.yoffsetEditFieldLabel.Text = 'y offset';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.Text = 'save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EyeCalibration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end