classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RewardButton               matlab.ui.control.StateButton
        yoffsetEditFieldLabel      matlab.ui.control.Label
        yoffset                    matlab.ui.control.NumericEditField
        ygainEditFieldLabel        matlab.ui.control.Label
        ygain                      matlab.ui.control.NumericEditField
        xoffsetEditFieldLabel      matlab.ui.control.Label
        xoffset                    matlab.ui.control.NumericEditField
        xgain                      matlab.ui.control.NumericEditField
        xgainEditFieldLabel        matlab.ui.control.Label
        DIRButton                  matlab.ui.control.Button
        Dir                        matlab.ui.control.EditField
        FinalizeButton             matlab.ui.control.Button
        SubjectNameEditField       matlab.ui.control.EditField
        SubjectNameEditFieldLabel  matlab.ui.control.Label
        STOPButton                 matlab.ui.control.StateButton
        RuncalibrationCheckBox     matlab.ui.control.CheckBox
        STARTButton                matlab.ui.control.Button
        InformationTextArea        matlab.ui.control.TextArea
        InformationTextAreaLabel   matlab.ui.control.Label
    end


    properties (Access = private)

    end

    
    methods (Access = public)
        function RewardButtonPushed_external(app,~,e)
            reward_supp(app,e)
        end

        function insToTxtbox(app,str)
            txtstr=sprintf('%s\n%s\n',str, string(get(app.InformationTextArea,'Value')));
            set(app.InformationTextArea,'Value',txtstr);
        end

        function savestate(app,int,e)
            %% save parameters and trials
            bckpname=fullfile(e.dir,...
                [e.subject_name,datestr(now, '_yymmdd')],...
                datestr(now, 'HHMMSS'));
            mkdir(bckpname)
            addpath(bckpname);

            taskpath=fullfile(fileparts(which('Main_function')), 'Tasks');
            copyfile(taskpath, fullfile(bckpname,'Tasks'))

            fileID = fopen(fullfile(bckpname, 'description.txt'),'W');
            fprintf(fileID,'%s','starting trial: ',num2str(int.trial.trialnum));
            fclose(fileID);
            %% back up data up to this point
            save(fullfile(bckpname(1:end-7),'e'),'e')
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(fileparts(which('Main_function'))));

            defDir=fullfile(pwd,'data');
            set(app.Dir,'Value',defDir)

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)

            app.insToTxtbox('Starting trials')
            app.STARTButton.Enable='off';
            app.RuncalibrationCheckBox.Enable = 'off';
            app.SubjectNameEditField.Editable = 'off';

            isinter = evalin( 'base', 'exist(''inter'',''var'') == 1' );
            ise = evalin( 'base', 'exist(''e'',''var'') == 1' );

            if isinter && ~ise
                inter=evalin('base','inter');

                    [inter, e]=Main_function(app,inter);

            elseif ise && ~isinter
                e=evalin('base','e');

                    [inter, e]=Main_function(app,[],e);

            elseif ise && isinter
                e=evalin('base','e');
                inter=evalin('base','inter');

                    [inter, e]=Main_function(app,inter,e);

            elseif ~ise && ~isinter
                [inter, e]=Main_function(app);
            end

            assignin('base','e',e)
            assignin('base','inter',inter)

            app.STARTButton.Enable='on';
            app.RuncalibrationCheckBox.Enable = 'on';
            app.SubjectNameEditField.Editable = 'on';

            app.RuncalibrationCheckBox.Value = 0;
        end

        % Button pushed function: FinalizeButton
        function FinalizeButtonPushed(app, event)


            app.insToTxtbox('archiving data')
            dirname=fullfile(app.Dir.Value, ...
                [app.SubjectNameEditField.Value,datestr(now, '_yymmdd')]);
            if ~isfile([dirname '.zip'])
                zip(dirname, dirname)
            else
                addon=string(length(dir([dirname '*']))+1);
                zip(append(dirname,'_', addon),...
                    dirname)
            end
            rmdir(dirname,'s')
            evalin( 'base', 'clear' );
            sca;
            app.delete
        end

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
            value = app.STOPButton.Value;
            if value==1
                app.insToTxtbox('stopping trials please wait')
            end
        end

        % Button pushed function: DIRButton
        function DIRButtonPushed(app, event)
            dir=uigetdir;
            set(app.Dir,'Value',dir)
        end

        % Callback function
        function SetCalibrationButtonValueChanged(app, event)
            value = app.SetCalibrationButton.Value;
            if value==1
                insToTxtbox(app, 'setting calibration please wait')
            end
        end

        % Value changed function: RewardButton
        function RewardButtonValueChanged(app, event)
            value = app.RewardButton.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InformationTextAreaLabel = uilabel(app.UIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.UIFigure);
            app.InformationTextArea.Editable = 'off';
            app.InformationTextArea.Value = {' '};
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.RuncalibrationCheckBox = uicheckbox(app.UIFigure);
            app.RuncalibrationCheckBox.Text = 'Run calibration';
            app.STOPButton = uibutton(app.UIFigure, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.SubjectNameEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectNameEditFieldLabel.Text = 'Subject Name';
            app.SubjectNameEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectNameEditField.Value = 'NAME';
            app.FinalizeButton = uibutton(app.UIFigure, 'push');
            app.FinalizeButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizeButtonPushed, true);
            app.FinalizeButton.Enable = 'off';
            app.FinalizeButton.Text = 'Finalize';
            app.Dir = uieditfield(app.UIFigure, 'text');
            app.DIRButton = uibutton(app.UIFigure, 'push');
            app.DIRButton.ButtonPushedFcn = createCallbackFcn(app, @DIRButtonPushed, true);
            app.DIRButton.Text = 'DIR';
            app.xgainEditFieldLabel = uilabel(app.UIFigure);
            app.xgainEditFieldLabel.Text = 'x gain';
            app.xgain = uieditfield(app.UIFigure, 'numeric');
            app.xoffset = uieditfield(app.UIFigure, 'numeric');
            app.xoffsetEditFieldLabel = uilabel(app.UIFigure);
            app.xoffsetEditFieldLabel.Text = 'x offset';
            app.ygain = uieditfield(app.UIFigure, 'numeric');
            app.ygainEditFieldLabel = uilabel(app.UIFigure);
            app.ygainEditFieldLabel.Text = 'y gain';
            app.yoffset = uieditfield(app.UIFigure, 'numeric');
            app.yoffsetEditFieldLabel = uilabel(app.UIFigure);
            app.yoffsetEditFieldLabel.Text = 'y offset';
            app.RewardButton = uibutton(app.UIFigure, 'state');
            app.RewardButton.ValueChangedFcn = createCallbackFcn(app, @RewardButtonValueChanged, true);
            app.RewardButton.Text = 'Reward';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end