classdef LiveParams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UpdateButton    matlab.ui.control.Button
        TabGroup        matlab.ui.container.TabGroup
        TrialtypesTab   matlab.ui.container.Tab
        MaxConsecutiveFailuresEditField  matlab.ui.control.NumericEditField
        MaxConsecutiveFailuresEditFieldLabel  matlab.ui.control.Label
        FilesTable      matlab.ui.control.Table
        TargetsTab      matlab.ui.container.Tab
        TargetsTable    matlab.ui.control.Table
        IntervalsTab    matlab.ui.container.Tab
        IntervalsTable  matlab.ui.control.Table
    end

    
    properties (Access = private)
        MHost2 % Description
        e = []% Description
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MHost2)
            
            folpath=fullfile(pwd,'Tasks');
            contents=dir(folpath);
            justnames={contents.name};
            filesonly=contains(justnames,'.m');
            cellx=sum(filesonly);
            evenspread=repmat(ceil(100/cellx),cellx,1);
            trialtable=[justnames(filesonly)',num2cell(evenspread)];
            set(app.FilesTable,'Data',trialtable)
            
         
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            tabledata=app.FilesTable.Data;
            app.MHost2=tabledata;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrialtypesTab = uitab(app.TabGroup);
            app.TrialtypesTab.Title = 'Trialtypes';
            app.FilesTable = uitable(app.TrialtypesTab);
            app.FilesTable.ColumnName = {'Trial/Task'; 'Occurrence'};
            app.FilesTable.RowName = {};
            app.FilesTable.ColumnSortable = true;
            app.FilesTable.ColumnEditable = true;
            app.MaxConsecutiveFailuresEditFieldLabel = uilabel(app.TrialtypesTab);
            app.MaxConsecutiveFailuresEditFieldLabel.Text = {'Max Consecutive'; 'Failures'};
            app.MaxConsecutiveFailuresEditField = uieditfield(app.TrialtypesTab, 'numeric');
            app.MaxConsecutiveFailuresEditField.Value = 5;
            app.TargetsTab = uitab(app.TabGroup);
            app.TargetsTab.Title = 'Targets';
            app.TargetsTable = uitable(app.TargetsTab);
            app.TargetsTable.ColumnName = {'Target'; 'Position'; 'Color'; 'Window'; 'Size'; 'Speed'; 'Direction'};
            app.TargetsTable.RowName = {};
            app.TargetsTable.ColumnSortable = true;
            app.TargetsTable.ColumnEditable = true;
            app.IntervalsTab = uitab(app.TabGroup);
            app.IntervalsTab.Title = 'Intervals';
            app.IntervalsTable = uitable(app.IntervalsTab);
            app.IntervalsTable.ColumnName = {'Name'; 'Duration'; 'Probability'; 'Sound'};
            app.IntervalsTable.RowName = {};
            app.IntervalsTable.ColumnSortable = true;
            app.IntervalsTable.ColumnEditable = true;
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiveParams(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end