classdef ScreenInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        saveconfigurationButton       matlab.ui.control.Button
        PixelsxyEditField             matlab.ui.control.EditField
        PixelsxyEditFieldLabel        matlab.ui.control.Label
        degreesxyEditField            matlab.ui.control.EditField
        degreesxyEditFieldLabel       matlab.ui.control.Label
        degreesthetarEditField        matlab.ui.control.EditField
        degreesrthetaLabel            matlab.ui.control.Label
        truecenterxyEditField         matlab.ui.control.EditField
        truecenterxyEditFieldLabel    matlab.ui.control.Label
        centerpixelsxyEditField       matlab.ui.control.EditField
        centerpixelsxyEditFieldLabel  matlab.ui.control.Label
        distancefromscreenEditField   matlab.ui.control.NumericEditField
        ScreendimensionsPanel         matlab.ui.container.Panel
        setcustominfoButton           matlab.ui.control.Button
        getnewinfoButton              matlab.ui.control.Button
        ResolutionYEditField          matlab.ui.control.NumericEditField
        ResolutionYEditFieldLabel     matlab.ui.control.Label
        ResolutionXEditField          matlab.ui.control.NumericEditField
        ResolutionXEditFieldLabel     matlab.ui.control.Label
        HeightmmEditField             matlab.ui.control.NumericEditField
        HeightmmEditFieldLabel        matlab.ui.control.Label
        WidthmmEditField              matlab.ui.control.NumericEditField
        WidthmmEditFieldLabel         matlab.ui.control.Label
        ScreenEditField               matlab.ui.control.NumericEditField
        ScreenEditFieldLabel          matlab.ui.control.Label
        distancefromscreenEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        screenInfo % Description
        ini = IniConfig();
        degreesPerPixel
    end
    
    methods (Access = private)        
        
        function app = getScreenInfo(app,sn)
            app.screenInfo=Screen('resolution',sn);
            
            [app.screenInfo.sizex,app.screenInfo.sizey]=Screen('DisplaySize',sn);

            app.ScreenEditField.Value=sn;
            app.WidthmmEditField.Value=app.screenInfo.sizex;            
            app.HeightmmEditField.Value=app.screenInfo.sizey;            
            app.ResolutionXEditField.Value=app.screenInfo.width;
            app.ResolutionYEditField.Value=app.screenInfo.height;

            app.setCenterPixels

            app.degperpix;
        end
        
        
        function setCenterPixels(app)
            xcenter=app.ResolutionXEditField.Value/2;
            ycenter=app.ResolutionYEditField.Value/2;

            app.centerpixelsxyEditField.Value=num2str([xcenter,ycenter]);
        end
        
        function app = degperpix(app)
            distance=app.distancefromscreenEditField.Value;
            XpixSize=app.WidthmmEditField.Value/app.ResolutionXEditField.Value;
            YpixSize=app.HeightmmEditField.Value/app.ResolutionYEditField.Value;
            
            app.degreesPerPixel(1)=atand(XpixSize/distance);
            app.degreesPerPixel(2)=atand(YpixSize/distance);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

                isini=app.ini.ReadFile('inis/ScreenParams.ini');

                if ~isini
                    errordlg('ini not found. Missing or in the wrong path.')
                elseif isini
                    app.HeightmmEditField.Value=app.ini.GetValues('screen info','height');
                    app.WidthmmEditField.Value=app.ini.GetValues('screen info','width');
                    app.ResolutionXEditField.Value=app.ini.GetValues('screen info','xres');
                    app.ResolutionYEditField.Value=app.ini.GetValues('screen info','yres');

                    app.distancefromscreenEditField.Value=app.ini.GetValues('for deg2pix','subject distance');
                    app.degreesPerPixel(1)=app.ini.GetValues('for deg2pix','xDegreesPerPixel');
                    app.degreesPerPixel(2)=app.ini.GetValues('for deg2pix','yDegreesPerPixel');
                    app.truecenterxyEditField.Value=num2str(app.ini.GetValues('for deg2pix','true center'));

                    app.setCenterPixels;
                end 
        end

        % Value changed function: ScreenEditField
        function ScreenEditFieldValueChanged(app, event)
            sn = app.ScreenEditField.Value;
            app.screenInfo=app.getScreenInfo(sn);
        end

        % Value changed function: ResolutionXEditField
        function ResolutionXEditFieldValueChanged(app, event)

            setCenterPixels(app)
            app.degperpix
        end

        % Value changed function: ResolutionYEditField
        function ResolutionYEditFieldValueChanged(app, event)

            setCenterPixels(app)
            app.degperpix;
        end

        % Button pushed function: getnewinfoButton
        function getnewinfoButtonPushed(app, event)
            % find external screen
            s=Screen('Screens');
            sn=max(s);
            
            %get resolution and size
            app.screenInfo=app.getScreenInfo(sn);

            % write to ini
            
        end

        % Value changed function: degreesxyEditField
        function degreesxyEditFieldValueChanged(app, event)
            desiredXY=str2num(app.degreesxyEditField.Value);
            centerXY=str2num(app.truecenterxyEditField.Value);

            X=floor(centerXY(1)+desiredXY(1)/app.degreesPerPixel(1));
            Y=floor(centerXY(2)+desiredXY(2)/app.degreesPerPixel(2));

            app.PixelsxyEditField.Value=num2str([X,Y]); 
        end

        % Value changed function: distancefromscreenEditField
        function distancefromscreenEditFieldValueChanged(app, event)
            app.degperpix
            
        end

        % Button pushed function: saveconfigurationButton
        function saveconfigurationButtonPushed(app, event)
            app.ini.SetValues('screen info',{'width','height','xres','yres'},...
                {app.WidthmmEditField.Value,...
                app.HeightmmEditField.Value,...
                app.ResolutionXEditField.Value,...
                app.ResolutionYEditField.Value});         
            
            app.ini.SetValues('for deg2pix',{'subject distance','xDegreesPerPixel','yDegreesPerPixel','true center'},...
                {num2str(app.distancefromscreenEditField.Value),...
                num2str(app.degreesPerPixel(1)),...
                num2str(app.degreesPerPixel(2)),...
                app.truecenterxyEditField.Value});

            app.ini.WriteFile('inis/ScreenParams.ini');
        end

        % Value changed function: degreesthetarEditField
        function degreesthetarEditFieldValueChanged(app, event)
            thetar=str2num(app.degreesthetarEditField.Value);
            [desiredX, desiredY]=pol2cart(deg2rad(thetar(1)),thetar(2));
            centerXY=str2num(app.truecenterxyEditField.Value);

            X=floor(centerXY(1)+desiredX/app.degreesPerPixel(1));
            Y=floor(centerXY(2)+desiredY/app.degreesPerPixel(2));

            app.PixelsxyEditField.Value=num2str([X,Y]); 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.distancefromscreenEditFieldLabel = uilabel(app.UIFigure);
            app.distancefromscreenEditFieldLabel.Text = 'distance from screen';
            app.ScreendimensionsPanel = uipanel(app.UIFigure);
            app.ScreendimensionsPanel.Title = 'Screen dimensions';
            app.ScreenEditFieldLabel = uilabel(app.ScreendimensionsPanel);
            app.ScreenEditFieldLabel.Text = 'Screen #';
            app.ScreenEditField = uieditfield(app.ScreendimensionsPanel, 'numeric');
            app.ScreenEditField.ValueChangedFcn = createCallbackFcn(app, @ScreenEditFieldValueChanged, true);
            app.WidthmmEditFieldLabel = uilabel(app.ScreendimensionsPanel);
            app.WidthmmEditFieldLabel.Text = 'Width (mm)';
            app.WidthmmEditField = uieditfield(app.ScreendimensionsPanel, 'numeric');
            app.HeightmmEditFieldLabel = uilabel(app.ScreendimensionsPanel);
            app.HeightmmEditFieldLabel.Text = 'Height (mm)';
            app.HeightmmEditField = uieditfield(app.ScreendimensionsPanel, 'numeric');
            app.ResolutionXEditFieldLabel = uilabel(app.ScreendimensionsPanel);
            app.ResolutionXEditFieldLabel.Text = 'Resolution X';
            app.ResolutionXEditField = uieditfield(app.ScreendimensionsPanel, 'numeric');
            app.ResolutionXEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionXEditFieldValueChanged, true);
            app.ResolutionYEditFieldLabel = uilabel(app.ScreendimensionsPanel);
            app.ResolutionYEditFieldLabel.Text = 'Resolution Y';
            app.ResolutionYEditField = uieditfield(app.ScreendimensionsPanel, 'numeric');
            app.ResolutionYEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionYEditFieldValueChanged, true);
            app.getnewinfoButton = uibutton(app.ScreendimensionsPanel, 'push');
            app.getnewinfoButton.ButtonPushedFcn = createCallbackFcn(app, @getnewinfoButtonPushed, true);
            app.getnewinfoButton.Text = 'get new info';
            app.setcustominfoButton = uibutton(app.ScreendimensionsPanel, 'push');
            app.setcustominfoButton.Text = 'set custom info';
            app.distancefromscreenEditField = uieditfield(app.UIFigure, 'numeric');
            app.distancefromscreenEditField.ValueChangedFcn = createCallbackFcn(app, @distancefromscreenEditFieldValueChanged, true);
            app.distancefromscreenEditField.Value = 1000;
            app.centerpixelsxyEditFieldLabel = uilabel(app.UIFigure);
            app.centerpixelsxyEditFieldLabel.Text = 'center pixels [x,y]';
            app.centerpixelsxyEditField = uieditfield(app.UIFigure, 'text');
            app.centerpixelsxyEditField.Editable = 'off';
            app.truecenterxyEditFieldLabel = uilabel(app.UIFigure);
            app.truecenterxyEditFieldLabel.Text = 'true center [x,y]';
            app.truecenterxyEditField = uieditfield(app.UIFigure, 'text');
            app.degreesrthetaLabel = uilabel(app.UIFigure);
            app.degreesrthetaLabel.Text = 'degrees [theta,r]';
            app.degreesthetarEditField = uieditfield(app.UIFigure, 'text');
            app.degreesthetarEditField.ValueChangedFcn = createCallbackFcn(app, @degreesthetarEditFieldValueChanged, true);
            app.degreesxyEditFieldLabel = uilabel(app.UIFigure);
            app.degreesxyEditFieldLabel.Text = 'degrees [x,y]';
            app.degreesxyEditField = uieditfield(app.UIFigure, 'text');
            app.degreesxyEditField.ValueChangedFcn = createCallbackFcn(app, @degreesxyEditFieldValueChanged, true);
            app.PixelsxyEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsxyEditFieldLabel.Text = 'Pixels [x,y]';
            app.PixelsxyEditField = uieditfield(app.UIFigure, 'text');
            app.saveconfigurationButton = uibutton(app.UIFigure, 'push');
            app.saveconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @saveconfigurationButtonPushed, true);
            app.saveconfigurationButton.Text = 'save configuration';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScreenInfo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end