classdef TrialSelect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
    end

    
    properties (Access = private)
        MHost2 % Description
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MHost2)
            folpath=fullfile(pwd,'Tasks');
            contents=dir(folpath);
            justnames={contents.name};
            filesonly=contains(justnames,'.m');
            cellx=sum(filesonly);
            evenspread=repmat(ceil(100/cellx),cellx,1);
            tabledata=[justnames(filesonly)',num2cell(evenspread)];
            set(app.UITable,'Data',tabledata)
            
            app.MHost2=tabledata;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Trial/Task'; 'Occurrence'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrialSelect(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end