classdef LiveParams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParametersUIFigure             matlab.ui.Figure
        MaxConsecutiveFailuresEditField  matlab.ui.control.NumericEditField
        MaxConsecutiveFailuresEditFieldLabel  matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        TrialtypesTab                  matlab.ui.container.Tab
        forthismanytrialsSpinner       matlab.ui.control.Spinner
        forthismanytrialsSpinnerLabel  matlab.ui.control.Label
        RepeatfailedtrialsCheckBox     matlab.ui.control.CheckBox
        DistributeButton               matlab.ui.control.Button
        TasksDirButton                 matlab.ui.control.Button
        Dir                            matlab.ui.control.EditField
        FilesTable                     matlab.ui.control.Table
        TargetsTab                     matlab.ui.container.Tab
        TargetsTable                   matlab.ui.control.Table
        IntervalsTab                   matlab.ui.container.Tab
        IntervalsTable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        MHost2 % Description
        e = []% Description
        ini = IniConfig();
    end
    
    methods (Access = private)     
        
        
        function setTaskTable(app)
            folpath=fullfile(app.Dir.Value);
            contents=dir(folpath);
            justnames={contents.name};
            filesonly=contains(justnames,'.m');
            cellx=sum(filesonly);
            evenspread=repmat(ceil(100/cellx),cellx,1);
            trialtable=[justnames(filesonly)',num2cell(evenspread)];
            set(app.FilesTable,'Data',trialtable)
            copyfile(folpath,'.Tasks_Internal')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MHost2)
           %% clear internal task folder
            theFiles = dir('.Tasks_Internal');
            for k = 1 : length(theFiles)
              baseFileName = theFiles(k).name;
              fullFileName = fullfile('.Tasks_Internal', baseFileName);
              delete(fullFileName);
            end

            isini=app.ini.ReadFile('inis/ScreenParams.ini');

            if ~isini
                errordlg('ini not found. Missing or in the wrong path.')
            elseif isini
                app.Dir.Value = app.ini.GetValues('last filepaths','tasks');         
            end 
            app.setTaskTable;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            for i=1:size(app.FilesTable.Data,1) 
                if isnan(app.FilesTable.Data{i,2})
                    app.FilesTable.Data{i,2}=0;
                end
            end
            tabledata=app.FilesTable.Data;
            app.MHost2=tabledata;

        end

        % Button pushed function: TasksDirButton
        function TasksDirButtonPushed(app, event)
            dir=uigetdir('~/Documents','select the folder with tasks');
            set(app.Dir,'Value',dir)

            app.ini.ReadFile('inis/ScreenParams.ini');
            app.ini.SetValues('last filepaths','tasks',...
                app.Dir.Value);
            app.ini.WriteFile('inis/ScreenParams.ini');
            app.setTaskTable;
        end

        % Close request function: ParametersUIFigure
        function ParametersUIFigureCloseRequest(app, event)
            
            app.ini.ReadFile('inis/ScreenParams.ini');
            app.ini.SetValues('last filepaths','tasks',...
                app.Dir.Value);
            app.ini.WriteFile('inis/ScreenParams.ini');
            delete(app);
            
        end

        % Callback function
        function CLEARButtonPushed(app, event)
            for i=1:size(app.FilesTable.Data,1); app.FilesTable.Data{i,2}=0;end
        end

        % Cell edit callback: FilesTable
        function FilesTableCellEdit(app, event)
            for i=1:size(app.FilesTable.Data,1) 
                if isnan(app.FilesTable.Data{i,2})
                    app.FilesTable.Data{i,2}=0;
                end
            end            
        end

        % Button pushed function: DistributeButton
        function DistributeButtonPushed(app, event)
            for i=1:size(app.FilesTable.Data,1) %first clear
                app.FilesTable.Data{i,2}=0;
            end

            for i=1:size(app.FilesTable.Selection,1)%first clear
                app.FilesTable.Data{app.FilesTable.Selection(i,1),2}=1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParametersUIFigure = uifigure('Visible', 'off');
            app.ParametersUIFigure.Name = 'Parameters';
            app.ParametersUIFigure.CloseRequestFcn = createCallbackFcn(app, @ParametersUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.ParametersUIFigure);
            app.TrialtypesTab = uitab(app.TabGroup);
            app.TrialtypesTab.Title = 'Trialtypes';
            app.FilesTable = uitable(app.TrialtypesTab);
            app.FilesTable.ColumnName = {'Trial/Task'; 'Occurrence'};
            app.FilesTable.RowName = {};
            app.FilesTable.ColumnSortable = true;
            app.FilesTable.ColumnEditable = true;
            app.FilesTable.CellEditCallback = createCallbackFcn(app, @FilesTableCellEdit, true);
            app.Dir = uieditfield(app.TrialtypesTab, 'text');
            app.TasksDirButton = uibutton(app.TrialtypesTab, 'push');
            app.TasksDirButton.ButtonPushedFcn = createCallbackFcn(app, @TasksDirButtonPushed, true);
            app.TasksDirButton.Text = 'Tasks Dir';
            app.DistributeButton = uibutton(app.TrialtypesTab, 'push');
            app.DistributeButton.ButtonPushedFcn = createCallbackFcn(app, @DistributeButtonPushed, true);
            app.DistributeButton.Text = 'Distribute';
            app.RepeatfailedtrialsCheckBox = uicheckbox(app.TrialtypesTab);
            app.RepeatfailedtrialsCheckBox.Text = 'Repeat failed trials';
            app.RepeatfailedtrialsCheckBox.Value = true;
            app.forthismanytrialsSpinnerLabel = uilabel(app.TrialtypesTab);
            app.forthismanytrialsSpinnerLabel.Text = 'for this many trials';
            app.forthismanytrialsSpinner = uispinner(app.TrialtypesTab);
            app.forthismanytrialsSpinner.Limits = [1 Inf];
            app.forthismanytrialsSpinner.Value = 3;
            app.TargetsTab = uitab(app.TabGroup);
            app.TargetsTab.Title = 'Targets';
            app.TargetsTable = uitable(app.TargetsTab);
            app.TargetsTable.ColumnName = {'Target'; 'Position'; 'Degree Type'; 'Window'; 'Size'; 'Speed'; 'Direction'};
            app.TargetsTable.RowName = {};
            app.TargetsTable.ColumnSortable = true;
            app.TargetsTable.ColumnEditable = true;
            app.IntervalsTab = uitab(app.TabGroup);
            app.IntervalsTab.Title = 'Intervals';
            app.IntervalsTable = uitable(app.IntervalsTab);
            app.IntervalsTable.ColumnName = {'Name'; 'Duration'; 'Probability'; 'Sound'};
            app.IntervalsTable.RowName = {};
            app.IntervalsTable.ColumnSortable = true;
            app.IntervalsTable.ColumnEditable = true;
            app.UpdateButton = uibutton(app.ParametersUIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.MaxConsecutiveFailuresEditFieldLabel = uilabel(app.ParametersUIFigure);
            app.MaxConsecutiveFailuresEditFieldLabel.Text = {'Max Consecutive'; 'Failures'};
            app.MaxConsecutiveFailuresEditField = uieditfield(app.ParametersUIFigure, 'numeric');
            app.MaxConsecutiveFailuresEditField.Value = 500;
            % Show the figure after all components are created
            app.ParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiveParams(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParametersUIFigure)
        end
    end
end