classdef YTplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        YT_2      matlab.ui.control.UIAxes
        XY        matlab.ui.control.UIAxes
        YT        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        gr; % Description
        state='stop';
        started=0;
        keep_plotting=1;
    end
    
    methods (Access = private)
        function idle_loop(app)
            while app.keep_plotting
            timestamps=[];
            timer=[];
            eyeposx=[];
            eyeposy=[];
            counter=0;
            app.started = 0;
            % app.state=app.gr.activestatename;
            app.state='aaaa';
            [xsaccade]=FakeSaccades(20,0.002,5000);
            [ysaccade]=FakeSaccades(20,0.002,5000);
                while ~strcmp(app.state,'stop')
                    counter=counter+1;
                    if app.started == 0
                        timer = getsecs;
                        app.started=1;
                    end
                    roundedTS=ceil((getsecs-timer)*1000);
                    timestamps=1:roundedTS;
                    if roundedTS>5000
                        app.state='stop';
                        break
                    end
                    eyeposx=xsaccade(1:roundedTS);
                    eyeposy=ysaccade(1:roundedTS);
                    %eyepos=pix2deg(app.gr.eye.geteye);
                    %eyeposy=pix2deg(app.gr.eye.geteye);
                    % eyepos_hypot=hypot(eyepos(1),eyepos(2));                
                    % write(asynBuff,eyepos_hypot);
                    %     if asyncBuff.NumUnreadSamples >= 50
                    %         eyeposbuffered = read(asyncBuff,256);
                    %         [yessaccade,saccadestart,saccadeend]=app.detectsaccades(eyeposbuffered,);
                    %     end
                    % eyeposx=[eyeposx eyepos_hypot];
                    plot(app.YT,timestamps,eyeposx,'r');
                    hold(app.YT,'on')
                    plot(app.YT,timestamps,eyeposy,'g');
                    app.detectsaccade(eyeposx,eyeposy);
                    drawnow
                    cla(app.YT)
                end
                cla(app.XY)
            end
        end
        
        function detectsaccade(app,eyeposx,eyeposy)
            saccx=nan(1,length(eyeposx));
            saccy=nan(1,length(eyeposy));

            velindex=abs((diff(hypot(eyeposx,eyeposy))*1000))>10;

            saccx(velindex)=eyeposx(velindex);
            saccy(velindex)=eyeposy(velindex);

            hold(app.XY,'on')
            plot(app.XY,saccx,saccy,'.k')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.YT.XLim=[0 5000];
            app.YT.YLim=[0 25];
            app.idle_loop
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YT = uiaxes(app.UIFigure);
            title(app.YT, 'eye position')
            xlabel(app.YT, 'Time (ms)')
            zlabel(app.YT, 'Z')
            app.YT.XLim = [0 5];
            app.XY = uiaxes(app.UIFigure);
            zlabel(app.XY, 'Z')
            app.XY.XLim = [-30 30];
            app.XY.YLim = [-30 30];
            app.YT_2 = uiaxes(app.UIFigure);
            title(app.YT_2, 'spikes')
            xlabel(app.YT_2, 'Time (ms)')
            zlabel(app.YT_2, 'Z')
            app.YT_2.XLim = [0 5];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = YTplot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end